classdef OxArpes_DataViewer_2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARPES2DDataViewerUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        TabGroup                   matlab.ui.container.TabGroup
        PositionTab                matlab.ui.container.Tab
        GridLayout3                matlab.ui.container.GridLayout
        AxisUnitCheckBox           matlab.ui.control.CheckBox
        EqualAxisCheckBox          matlab.ui.control.CheckBox
        CrossHairCheckBox          matlab.ui.control.CheckBox
        YEditField_2               matlab.ui.control.NumericEditField
        YEditField_2Label          matlab.ui.control.Label
        XEditField_2               matlab.ui.control.NumericEditField
        XEditField_2Label          matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        ColourTab                  matlab.ui.container.Tab
        GridLayout4                matlab.ui.container.GridLayout
        ColourMapDropDown          matlab.ui.control.DropDown
        ColourMapDropDownLabel     matlab.ui.control.Label
        FlipCheckBox               matlab.ui.control.CheckBox
        ColourRangeSlider          matlab.ui.control.RangeSlider
        ColourRangeSliderLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        DATA_IN
        XX % Description
        YY
        DATA_X_
        DATA_Y_

        FigX_
        FigY_
        FigXY
        FigXY_ch
        
        DATA_VALUE_MAX
    end
    
    methods (Access = private)
        
        
        function slice_data(app)
            % YZ
            cond1 = ( abs(app.DATA_IN.x - app.XX) <= app.XEditField_2.Value);
            if ~any(cond1)
                [~,cond1] = min(abs(app.DATA_IN.x - app.XX));
            end
            app.DATA_X_ = squeeze(mean(app.DATA_IN.value(cond1,:),1))';

            % XZ
            cond2 = ( abs(app.DATA_IN.y - app.YY) <= app.YEditField_2.Value);
            if ~any(cond2)
                [~,cond2] = min(abs(app.DATA_IN.y - app.YY));
            end
            app.DATA_Y_ = squeeze(mean(app.DATA_IN.value(:,cond2),2))';

        end
        
        function display_data(app)
            app.FigXY = imagesc(app.UIAxes,app.DATA_IN.x,app.DATA_IN.y,app.DATA_IN.value');
            set(app.UIAxes,'YDir','normal');
            app.FigX_ = plot(app.UIAxes2,app.DATA_X_,app.DATA_IN.y);
            app.FigY_ = plot(app.UIAxes3,app.DATA_IN.x,app.DATA_Y_);

        end
        
        function update_positions(app,x,y)
            app.XX = x;
            app.YY = y;
            app.XEditField.Value = x;
            app.YEditField.Value = y;

            app.slice_data();
            app.update_images();

            app.FigXY_ch.Position = [x y];

        end
        
        function update_images(app)
            app.FigX_.XData = app.DATA_X_;
            app.FigY_.YData = app.DATA_Y_;

        end
        
        function update_colourmap(app)

            cm = app.ColourMapDropDown.Value;
            fp = app.FlipCheckBox.Value;
            if fp
                cmf = flip(eval(cm));
                colormap(app.UIAxes,cmf);
            else
                colormap(app.UIAxes,cm);
            end
            
            llmm = app.ColourRangeSlider.Value ./100 .*app.DATA_VALUE_MAX;
            clim(app.UIAxes,llmm);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DATA_INPUT)
            app.DATA_IN = DATA_INPUT;

            % initialise
            app.XX = mean(app.DATA_IN.x);
            app.YY = mean(app.DATA_IN.y);
            app.XEditField.Value = app.XX;
            app.YEditField.Value = app.YY;

            app.DATA_VALUE_MAX = max(app.DATA_IN.value,[],'all');

            % slice data
            app.slice_data();

            % display data
            app.display_data();

            % set crosshair
            app.FigXY_ch = drawcrosshair('Parent',app.UIAxes,'Position',[app.XX app.YY],'LineWidth',0.5,'Color','c');
            addlistener(app.FigXY_ch,'MovingROI',@(src,data) app.update_positions(data.CurrentPosition(1),data.CurrentPosition(2)));
        end

        % Value changed function: ColourMapDropDown, ColourRangeSlider, 
        % ...and 1 other component
        function ColourMapDropDownValueChanged(app, event)
            value = app.ColourMapDropDown.Value;
            app.update_colourmap();
        end

        % Value changed function: CrossHairCheckBox
        function CrossHairCheckBoxValueChanged(app, event)
            value = app.CrossHairCheckBox.Value;
            if value == 1
                app.FigXY_ch.Visible = 'on';
            else
                app.FigXY_ch.Visible = 'off';
            end
        end

        % Value changed function: AxisUnitCheckBox
        function AxisUnitCheckBoxValueChanged(app, event)
            value = app.AxisUnitCheckBox.Value;
            if value == 1
                app.UIAxes.XLabel.String = [app.DATA_IN.x_name ' (' app.DATA_IN.x_unit ')' ];
                app.UIAxes.YLabel.String = [app.DATA_IN.y_name ' (' app.DATA_IN.y_unit ')' ];
                app.UIAxes2.XLabel.String = 'Counts (a.u.)';
                app.UIAxes3.YLabel.String = 'Counts (a.u.)';
            else
                app.UIAxes.XLabel.String = [];
                app.UIAxes.YLabel.String = [];
                app.UIAxes2.XLabel.String = [];
                app.UIAxes3.YLabel.String = [];
            end
        end

        % Value changed function: XEditField, XEditField_2, YEditField, 
        % ...and 1 other component
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.update_positions(app.XEditField.Value,app.YEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ARPES2DDataViewerUIFigure = uifigure('Visible', 'off');
            app.ARPES2DDataViewerUIFigure.Name = 'ARPES 2D Data Viewer';
            app.GridLayout = uigridlayout(app.ARPES2DDataViewerUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.GridLayout3 = uigridlayout(app.PositionTab);
            app.XEditFieldLabel = uilabel(app.GridLayout3);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GridLayout3, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.4f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.GridLayout3);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.4f';
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField_2Label = uilabel(app.GridLayout3);
            app.XEditField_2Label.Text = 'ΔX';
            app.XEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.XEditField_2.ValueDisplayFormat = '%.4f';
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField_2.Value = 0.05;
            app.YEditField_2Label = uilabel(app.GridLayout3);
            app.YEditField_2Label.Text = 'ΔY';
            app.YEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.YEditField_2.ValueDisplayFormat = '%.4f';
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditField_2.Value = 0.05;
            app.CrossHairCheckBox = uicheckbox(app.GridLayout3);
            app.CrossHairCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrossHairCheckBoxValueChanged, true);
            app.CrossHairCheckBox.Text = 'Cross Hair';
            app.CrossHairCheckBox.Value = true;
            app.EqualAxisCheckBox = uicheckbox(app.GridLayout3);
            app.EqualAxisCheckBox.Text = 'Equal Axis';
            app.AxisUnitCheckBox = uicheckbox(app.GridLayout3);
            app.AxisUnitCheckBox.ValueChangedFcn = createCallbackFcn(app, @AxisUnitCheckBoxValueChanged, true);
            app.AxisUnitCheckBox.Text = 'Axis Unit';
            app.ColourTab = uitab(app.TabGroup);
            app.ColourTab.Title = 'Colour';
            app.GridLayout4 = uigridlayout(app.ColourTab);
            app.ColourRangeSliderLabel = uilabel(app.GridLayout4);
            app.ColourRangeSliderLabel.Text = 'Colour Range';
            app.ColourRangeSlider = uislider(app.GridLayout4, 'range');
            app.ColourRangeSlider.Limits = [0 150];
            app.ColourRangeSlider.ValueChangedFcn = createCallbackFcn(app, @ColourMapDropDownValueChanged, true);
            app.FlipCheckBox = uicheckbox(app.GridLayout4);
            app.FlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColourMapDropDownValueChanged, true);
            app.FlipCheckBox.Text = 'Flip';
            app.ColourMapDropDownLabel = uilabel(app.GridLayout4);
            app.ColourMapDropDownLabel.Text = 'Colour Map';
            app.ColourMapDropDown = uidropdown(app.GridLayout4);
            app.ColourMapDropDown.Items = {'gray', 'hot', 'cool', 'jet', 'parula', 'turbo', 'hsv', 'sky'};
            app.ColourMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourMapDropDownValueChanged, true);
            app.ColourMapDropDown.Value = 'sky';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            colormap(app.UIAxes, 'sky')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            app.UIAxes2.YLimitMethod = 'tight';
            app.UIAxes3 = uiaxes(app.GridLayout2);
            app.UIAxes3.XLimitMethod = 'tight';
            % Show the figure after all components are created
            app.ARPES2DDataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_DataViewer_2D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARPES2DDataViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARPES2DDataViewerUIFigure)
        end
    end
end