classdef OxArpes_DataList < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataBrowserUIFigure    matlab.ui.Figure
        Menu                   matlab.ui.container.Menu
        LoadDataMenu           matlab.ui.container.Menu
        VisualiseMenu_2        matlab.ui.container.Menu
        PlotBrillouinZoneMenu  matlab.ui.container.Menu
        PlotCutinBZMenu        matlab.ui.container.Menu
        BZBSplotMenu           matlab.ui.container.Menu
        ProcessMenu            matlab.ui.container.Menu
        BasicprocessMenu       matlab.ui.container.Menu
        FDDfitMenu             matlab.ui.container.Menu
        GridLayout             matlab.ui.container.GridLayout
        RefreshButton          matlab.ui.control.Button
        PlotButton             matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        TabGroup               matlab.ui.container.TabGroup
        PreviewTab             matlab.ui.container.Tab
        GridLayout3            matlab.ui.container.GridLayout
        Slider                 matlab.ui.control.Slider
        UIAxes                 matlab.ui.control.UIAxes
        OperationTab           matlab.ui.container.Tab
        ContextMenu            matlab.ui.container.ContextMenu
        QuickPlotMenu          matlab.ui.container.Menu
        PlotMenu               matlab.ui.container.Menu
        AlongXMenu             matlab.ui.container.Menu
        AlongYMenu             matlab.ui.container.Menu
        AlongZMenu             matlab.ui.container.Menu
        DOShorizontalMenu      matlab.ui.container.Menu
        DOSverticalMenu        matlab.ui.container.Menu
        DDataViewerMenu        matlab.ui.container.Menu
        EditVariableMenu       matlab.ui.container.Menu
        RenameMenu             matlab.ui.container.Menu
        DeleteMenu             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        selected_data_name % Selected data list
    end
    
    methods (Access = private)
        
        function results = getDataTable(app)
            vars = evalin('base','who');

            columnNames = {'Name' 'Type' 'Hv' 'Polarisation' 'PassEnergy' 'Mode' 'Temperature'};
            data = cell(length(vars),7);
            for i = 1:length(vars)
                var = evalin('base',vars{i});
                data{i,1} = vars{i};
                data{i,2} = class(var);
                try
                    data{i,3} = var.info.photon_energy;
                    data{i,4} = var.info.polarization;
                    data{i,5} = var.info.pass_energy;
                    data{i,6} = var.info.acquisition_mode;
                    data{i,7} = var.info.temperature;
                catch
                end
                
            end
            results = cell2table(data);
            results.Properties.VariableNames = columnNames;
        end
        
        function plot_selected_DOS(app,HorV)

            figure;
            hold on
        
            for i = 1:length(app.selected_data_name.Name)

                data = evalin('base',app.selected_data_name{i});

                if ndims(data.value) == 2 && min(size(data.value)) ~= 1
                    x = data.y;
                    y = squeeze(sum(data.value,1))';
                    E_name = [data.y_name ' (' data.y_unit ')'];
                elseif ndims(data.value) == 3
                    x = data.z;
                    data.value(isnan(data.value)) = 0;
                    y = squeeze(sum(data.value,[1 2]))';
                    E_name = [data.z_name ' (' data.z_unit ')'];
                elseif ndims(data.value) == 2 && min(size(data.value)) == 1
                    x = data.x;
                    y = data.value;
                    E_name = '';
                end
        
        %         if length(dataName) > 1
                    y = y/mean(y,"all");
        %         end
        
                switch HorV
                    case 'H'
                        plot(x,y);
                        xlabel(E_name);
                        ylabel('Counts (a.u.)');
                    case 'V'
                        plot(y,x);
                        ylabel(E_name);
                        xlabel('Counts (a.u.)');
                end
            end
            
            hold off
        
            if length(app.selected_data_name)==1
                title(app.selected_data_name{1},'Interpreter','none');
            else
                legend(app.selected_data_name{:},'Interpreter','none');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RefreshButtonPushed;


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            datalist = app.getDataTable();
            % app.UITable.ColumnName = datalist.Properties.VariableNames;
            app.UITable.Data = datalist;
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            loader_UI();
        end

        % Callback function: PlotButton, QuickPlotMenu, UITable
        function PlotButtonPushed(app, event)
            data = evalin('base',app.selected_data_name{1});
            try
                if ndims(data.value) == 3
                    PlotSlices(app.selected_data_name{1},'z');
                else
                    PlotSlices(app.selected_data_name{1},'x');
                end
            catch
            end

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            app.selected_data_name = app.UITable.Data(app.UITable.Selection,1);
            app.selected_data_name = app.selected_data_name.Name;
            data = evalin('base',app.selected_data_name{1});
            
            % update Preview tab
            if ndims(data.value) == 2 && size(data.value,1) ~=1 && size(data.value,2) ~=1
                % imagesc meshc
                imagesc(app.UIAxes,data.x,data.y,data.value');
                set(app.UIAxes,'YDir','normal');
                app.Slider.Enable = "off";
            elseif ndims(data.value) == 3
                app.Slider.Enable = "on";

                data_slice = sum(data.value(:,:,abs(data.z-mean(data.z))<0.1),3);

                imagesc(app.UIAxes,data.x,data.y,data_slice');
                set(app.UIAxes,'YDir','normal');
            end


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loader_UI();
        end

        % Callback function
        function FinestructureMenuSelected(app, event)
            OxArpes_Visualise();
        end

        % Callback function
        function FDDfitMenuSelected(app, event)
            FDD_new();
        end

        % Callback function
        function BZBSplotMenuSelected(app, event)
            BZBS_plotter();
        end

        % Menu selected function: PlotBrillouinZoneMenu
        function PlotBrillouinZoneMenuSelected(app, event)
            plotBZ_V1();
        end

        % Menu selected function: PlotCutinBZMenu
        function PlotCutinBZMenuSelected(app, event)
            kz_plot();
        end

        % Callback function
        function TruncateMenuSelected(app, event)
            td_demo();
        end

        % Callback function
        function NormaliseMenuSelected(app, event)
            self_normalization_demo();
        end

        % Menu selected function: EditVariableMenu
        function EditVariableMenuSelected(app, event)
            openvar(app.selected_data_name{1});
        end

        % Menu selected function: AlongXMenu
        function AlongXMenuSelected(app, event)
            PlotSlices(app.selected_data_name{1},'x');
        end

        % Menu selected function: AlongYMenu
        function AlongYMenuSelected(app, event)
            PlotSlices(app.selected_data_name{1},'y');
        end

        % Menu selected function: AlongZMenu
        function AlongZMenuSelected(app, event)
            PlotSlices(app.selected_data_name{1},'z');
        end

        % Menu selected function: DOShorizontalMenu
        function DOShorizontalMenuSelected(app, event)
            app.plot_selected_DOS('H');
        end

        % Menu selected function: DOSverticalMenu
        function DOSverticalMenuSelected(app, event)
            app.plot_selected_DOS('V');
        end

        % Menu selected function: DDataViewerMenu
        function DDataViewerMenuSelected(app, event)
            data = evalin('base',app.selected_data_name{1});
            OxArpes_DataViewer(data);
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            prompt = {'Please enter the new name'};
            dlgtitle = 'Rename a variable';
            fieldsize = [1 40];
            definput = {app.selected_data_name{1}};
            answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            
            old_var = evalin('base',app.selected_data_name{1});
            assignin('base',answer{1},old_var);
            evalin("base",['clear ' app.selected_data_name{1}]);
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            answer = questdlg(sprintf(['Would you like to delete all ' num2str(length(app.selected_data_name)) ' variable(s)?\n', app.selected_data_name{1} '\n...']), ...
                'Yes', 'No');
            % Handle response
            switch answer
                case 'Yes'
                    for i=1:length(app.selected_data_name)
                        evalin("base",['clear ' app.selected_data_name{i}]);
                    end
                case 'No'
                    return
            end

        end

        % Menu selected function: BZBSplotMenu
        function BZBSplotMenuSelected2(app, event)
            BZBS_plotter();
        end

        % Menu selected function: FDDfitMenu
        function FDDfitMenuSelected2(app, event)
            FDD_new();
        end

        % Menu selected function: BasicprocessMenu
        function BasicprocessMenuSelected2(app, event)
            OxArpes_DataProcess();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataBrowserUIFigure = uifigure('Visible', 'off');
            app.DataBrowserUIFigure.Name = 'Data Browser';
            app.Menu = uimenu(app.DataBrowserUIFigure);
            app.Menu.Text = 'Menu';
            app.LoadDataMenu = uimenu(app.Menu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.VisualiseMenu_2 = uimenu(app.DataBrowserUIFigure);
            app.VisualiseMenu_2.Text = 'Visualise';
            app.PlotBrillouinZoneMenu = uimenu(app.VisualiseMenu_2);
            app.PlotBrillouinZoneMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotBrillouinZoneMenuSelected, true);
            app.PlotBrillouinZoneMenu.Text = 'Plot Brillouin Zone';
            app.PlotCutinBZMenu = uimenu(app.VisualiseMenu_2);
            app.PlotCutinBZMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotCutinBZMenuSelected, true);
            app.PlotCutinBZMenu.Text = 'Plot Cut in BZ';
            app.BZBSplotMenu = uimenu(app.VisualiseMenu_2);
            app.BZBSplotMenu.MenuSelectedFcn = createCallbackFcn(app, @BZBSplotMenuSelected2, true);
            app.BZBSplotMenu.Separator = 'on';
            app.BZBSplotMenu.Text = 'BZBS plot';
            app.ProcessMenu = uimenu(app.DataBrowserUIFigure);
            app.ProcessMenu.Text = 'Process';
            app.BasicprocessMenu = uimenu(app.ProcessMenu);
            app.BasicprocessMenu.MenuSelectedFcn = createCallbackFcn(app, @BasicprocessMenuSelected2, true);
            app.BasicprocessMenu.Separator = 'on';
            app.BasicprocessMenu.Text = 'Basic process';
            app.FDDfitMenu = uimenu(app.ProcessMenu);
            app.FDDfitMenu.MenuSelectedFcn = createCallbackFcn(app, @FDDfitMenuSelected2, true);
            app.FDDfitMenu.Text = 'FDD fit';
            app.GridLayout = uigridlayout(app.DataBrowserUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.GridLayout3 = uigridlayout(app.PreviewTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.YDir = 'reverse';
            colormap(app.UIAxes, 'sky')
            app.Slider = uislider(app.GridLayout3);
            app.OperationTab = uitab(app.TabGroup);
            app.OperationTab.Title = 'Operation';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Name'; 'Type'; 'hv (eV)'; 'Polarisation'; 'PE'; 'Mode'; 'Temp (K)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RefreshButton = uibutton(app.GridLayout, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ContextMenu = uicontextmenu(app.DataBrowserUIFigure);
            app.QuickPlotMenu = uimenu(app.ContextMenu);
            app.QuickPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.QuickPlotMenu.Text = 'Quick Plot';
            app.PlotMenu = uimenu(app.ContextMenu);
            app.PlotMenu.Text = 'Plot';
            app.AlongXMenu = uimenu(app.PlotMenu);
            app.AlongXMenu.MenuSelectedFcn = createCallbackFcn(app, @AlongXMenuSelected, true);
            app.AlongXMenu.Text = 'Along X';
            app.AlongYMenu = uimenu(app.PlotMenu);
            app.AlongYMenu.MenuSelectedFcn = createCallbackFcn(app, @AlongYMenuSelected, true);
            app.AlongYMenu.Text = 'Along Y';
            app.AlongZMenu = uimenu(app.PlotMenu);
            app.AlongZMenu.MenuSelectedFcn = createCallbackFcn(app, @AlongZMenuSelected, true);
            app.AlongZMenu.Text = 'Along Z';
            app.DOShorizontalMenu = uimenu(app.PlotMenu);
            app.DOShorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @DOShorizontalMenuSelected, true);
            app.DOShorizontalMenu.Separator = 'on';
            app.DOShorizontalMenu.Text = 'DOS, horizontal';
            app.DOSverticalMenu = uimenu(app.PlotMenu);
            app.DOSverticalMenu.MenuSelectedFcn = createCallbackFcn(app, @DOSverticalMenuSelected, true);
            app.DOSverticalMenu.Text = 'DOS, vertical';
            app.DDataViewerMenu = uimenu(app.PlotMenu);
            app.DDataViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @DDataViewerMenuSelected, true);
            app.DDataViewerMenu.Separator = 'on';
            app.DDataViewerMenu.Text = '3D Data Viewer';
            app.EditVariableMenu = uimenu(app.ContextMenu);
            app.EditVariableMenu.MenuSelectedFcn = createCallbackFcn(app, @EditVariableMenuSelected, true);
            app.EditVariableMenu.Separator = 'on';
            app.EditVariableMenu.Text = 'Edit Variable';
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Separator = 'on';
            app.RenameMenu.Text = 'Rename';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.DataBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_DataList

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataBrowserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataBrowserUIFigure)
        end
    end
end