classdef OxArpes_ResolutionCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NoiseFloormeVEditField          matlab.ui.control.NumericEditField
        NoiseFloormeVEditFieldLabel     matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        ParametersPanel                 matlab.ui.container.Panel
        EhvEditField                    matlab.ui.control.NumericEditField
        EhvLabel                        matlab.ui.control.Label
        PhotonEnergyeVEditField         matlab.ui.control.NumericEditField
        PhotonEnergyeVEditFieldLabel    matlab.ui.control.Label
        TemperatureKEditField           matlab.ui.control.NumericEditField
        TemperatureKEditFieldLabel      matlab.ui.control.Label
        HemisphereRadiusmmDropDown      matlab.ui.control.DropDown
        HemisphereRadiusmmDropDownLabel  matlab.ui.control.Label
        SlitmDropDown                   matlab.ui.control.DropDown
        SlitmDropDownLabel              matlab.ui.control.Label
        PassEnergyeVDropDown            matlab.ui.control.DropDown
        PassEnergyeVDropDownLabel       matlab.ui.control.Label
        TotalResolutionmeVEditField     matlab.ui.control.NumericEditField
        TotalResolutionmeVEditFieldLabel  matlab.ui.control.Label
        TemperatureUncertaintymeVEditField  matlab.ui.control.NumericEditField
        TemperatureUncertaintymeVEditFieldLabel  matlab.ui.control.Label
        AnalyzerResolutionmeVEditField  matlab.ui.control.NumericEditField
        AnalyzerResolutionmeVEditFieldLabel  matlab.ui.control.Label
        BeamlineResolutionmeVEditField  matlab.ui.control.NumericEditField
        BeamlineResolutionmeVEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = getAnalyzerResolution(app)
            passEnergy_eV = str2num(app.PassEnergyeVDropDown.Value);
            slit_mm = str2num(app.SlitmDropDown.Value) / 1000;
            hemisphereRadius_mm = str2num(app.HemisphereRadiusmmDropDown.Value);

            results = 1000*passEnergy_eV*slit_mm/(2*hemisphereRadius_mm);
        end
        
        function results = getTemperatureUncertainty(app)
            results = 0.08617 * app.TemperatureKEditField.Value;
        end
        
        function results = getBeamlineResolution(app)
            results = app.PhotonEnergyeVEditField.Value * app.EhvEditField.Value * 1000;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            ana = app.getAnalyzerResolution();
            tem = app.getTemperatureUncertainty();

            app.AnalyzerResolutionmeVEditField.Value = ana;
            app.TemperatureUncertaintymeVEditField.Value = tem;

            bmln = app.getBeamlineResolution();
            app.BeamlineResolutionmeVEditField.Value = bmln;

            nos = app.NoiseFloormeVEditField.Value;

            Tot = sqrt(ana^2 +tem^2 + bmln^2 +nos^2);
            app.TotalResolutionmeVEditField.Value = Tot;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BeamlineResolutionmeVEditFieldLabel = uilabel(app.UIFigure);
            app.BeamlineResolutionmeVEditFieldLabel.Text = 'Beamline Resolution (meV)';
            app.BeamlineResolutionmeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnalyzerResolutionmeVEditFieldLabel = uilabel(app.UIFigure);
            app.AnalyzerResolutionmeVEditFieldLabel.Text = 'Analyzer Resolution (meV)';
            app.AnalyzerResolutionmeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnalyzerResolutionmeVEditField.Editable = 'off';
            app.TemperatureUncertaintymeVEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureUncertaintymeVEditFieldLabel.Text = 'Temperature Uncertainty (meV)';
            app.TemperatureUncertaintymeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureUncertaintymeVEditField.Editable = 'off';
            app.TotalResolutionmeVEditFieldLabel = uilabel(app.UIFigure);
            app.TotalResolutionmeVEditFieldLabel.Text = 'Total Resolution (meV)';
            app.TotalResolutionmeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalResolutionmeVEditField.Editable = 'off';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.PassEnergyeVDropDownLabel = uilabel(app.ParametersPanel);
            app.PassEnergyeVDropDownLabel.Text = 'Pass Energy (eV)';
            app.PassEnergyeVDropDown = uidropdown(app.ParametersPanel);
            app.PassEnergyeVDropDown.Items = {'5', '10', '20', '50', '100', '200'};
            app.PassEnergyeVDropDown.Value = '20';
            app.SlitmDropDownLabel = uilabel(app.ParametersPanel);
            app.SlitmDropDownLabel.Text = 'Slit (μm)';
            app.SlitmDropDown = uidropdown(app.ParametersPanel);
            app.SlitmDropDown.Items = {'50', '100', '200', '300', '400', '500', '800', '1000'};
            app.SlitmDropDown.Value = '200';
            app.HemisphereRadiusmmDropDownLabel = uilabel(app.ParametersPanel);
            app.HemisphereRadiusmmDropDownLabel.Text = 'Hemisphere Radius (mm)';
            app.HemisphereRadiusmmDropDown = uidropdown(app.ParametersPanel);
            app.HemisphereRadiusmmDropDown.Items = {'180', '200'};
            app.HemisphereRadiusmmDropDown.Value = '200';
            app.TemperatureKEditFieldLabel = uilabel(app.ParametersPanel);
            app.TemperatureKEditFieldLabel.Text = 'Temperature (K)';
            app.TemperatureKEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.TemperatureKEditField.Limits = [0 Inf];
            app.TemperatureKEditField.Value = 20;
            app.PhotonEnergyeVEditFieldLabel = uilabel(app.ParametersPanel);
            app.PhotonEnergyeVEditFieldLabel.Text = 'Photon Energy (eV)';
            app.PhotonEnergyeVEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PhotonEnergyeVEditField.Limits = [0 Inf];
            app.PhotonEnergyeVEditField.Value = 100;
            app.EhvLabel = uilabel(app.ParametersPanel);
            app.EhvLabel.Text = 'ΔE / hv';
            app.EhvEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.EhvEditField.Value = 0.0001;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.NoiseFloormeVEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseFloormeVEditFieldLabel.Text = 'Noise Floor (meV)';
            app.NoiseFloormeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseFloormeVEditField.Limits = [0 Inf];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxArpes_ResolutionCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end